classdef hlavniOkno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        vektortestovacchfrekvencEditField  matlab.ui.control.EditField
        vektortestovacchfrekvencEditFieldLabel  matlab.ui.control.Label
        SlysenoButton                   matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        mask                            matlab.ui.control.NumericEditField
        masknetestucha1ANO0NEEditFieldLabel  matlab.ui.control.Label
        zvysovani                       matlab.ui.control.NumericEditField
        zvysovaniintenzity1schod0spojEditFieldLabel  matlab.ui.control.Label
        pasmoMaxFreq                    matlab.ui.control.NumericEditField
        doEditFieldLabel                matlab.ui.control.Label
        sumIntenzita                    matlab.ui.control.NumericEditField
        intenzita090dBEditField_2Label  matlab.ui.control.Label
        pasmoMinFreq                    matlab.ui.control.NumericEditField
        frekvennpsmo120000HzodEditFieldLabel  matlab.ui.control.Label
        intenzitaMaskTonu               matlab.ui.control.NumericEditField
        intenzita090dBEditFieldLabel    matlab.ui.control.Label
        frekvenceMaskTonu               matlab.ui.control.NumericEditField
        frekvencetnu120000HzEditFieldLabel  matlab.ui.control.Label
        novaFrekvenceZobrazeni          matlab.ui.control.NumericEditField
        Novfrekvence120000HzEditFieldLabel  matlab.ui.control.Label
        NVODButton                      matlab.ui.control.Button
        nastavenhodnotyLabel            matlab.ui.control.Label
        NASTAVENButton                  matlab.ui.control.Button
        dBLabel                         matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        kontrolamaskovachoumuLabel      matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        kontrolamaskovachotnuLabel      matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        kontrolatestovachotnuLabel      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    %pořešit radio buttons

    properties (Access = private)

        nastaveni % Description
        navod
        freqVect = [125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];
        startMeasFlag = 0;
        dontSaveTime = 0;
        timeLeft = [];
        timeRight = [];
        measCounter = 1;
        leftMeas = 1;


    end

    methods (Access = public)
        %hlaní funkce pro přeposílání dat mezi main a nastavením
        function importNastaveni(app,MaskovaniNetestovanehoUcha,ZvysovaniIntenzityTonu,frequencyNew,frekvenceMaskTonu,intenzitaMaskTonu,pasmoMinFreq,pasmoMaxFreq,sumIntenzita,vektorFrekvenci)
            app.mask.Value=MaskovaniNetestovanehoUcha;
            app.zvysovani.Value=ZvysovaniIntenzityTonu;
            app.novaFrekvenceZobrazeni.Value=frequencyNew;
            %app.freqVect = frequencyNew;
            app.frekvenceMaskTonu.Value=frekvenceMaskTonu;
            app.intenzitaMaskTonu.Value=intenzitaMaskTonu;
            app.pasmoMinFreq.Value=pasmoMinFreq;
            app.pasmoMaxFreq.Value=pasmoMaxFreq;
            app.sumIntenzita.Value=sumIntenzita;
            app.vektortestovacchfrekvencEditField.Value= vektorFrekvenci;
            app.freqVect = str2num(vektorFrekvenci);
        end
    end

    %methods (Access = private)


    %     function results = Measurement(app)
    % 
    % 
    %         P_sampled = importdata('funkce60.mat');
    %         P_sampled = P_sampled'; % transpozice vektoru
    %         P_sampled(end) = [];    % zkrácení vektoru aby měl požadovanou délku
    % 
    %         Fs = 44100 ;   % nastavení vzorkovacího kmitočtu
    %         t_max = 60;   %časová délka signálu ale aby v gefu byla tahle hodnota je zapotřebí poděli pi*2(v grafu na x ukáže sice 10 ale)
    %         t = 0: (2*pi)/Fs : (2*pi*t_max - (2*pi)/Fs); %nastavení časové základny
    %         f = app.freqVect;%[125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];
    % 
    %         app.startMeasFlag = 1;
    % 
    %         for i = 1:1:numel(f)
    % 
    %             frequence = f(i);
    %             signal = sin(frequence*t);
    %             signal = signal.*P_sampled;%ramp;
    % 
    %             for k = 1:2
    %                 if k == 1
    %                     %přehrání sinusové vlny na různých frekvencích do levého ucha
    %                     sound([signal;zeros(size(t))],Fs);
    %                     tic
    % 
    %                     if app.buttonPushed ==1
    %                         app.timeLeft(i) = toc;
    %                     end
    %                     app.buttonPushed =0;
    %                     clear sound;
    %                 end
    % 
    %                 if k == 2
    %                     %přehrání sinusové vlny na různých frekvencích do pravého ucha
    %                     sound([zeros(size(t)); signal],Fs);
    %                     tic
    %                     %waitforbuttonpress;
    %                     if app.buttonPushed ==1
    %                         app.timeRight(i) = toc;
    %                     end
    %                     app.buttonPushed = 0;
    %                     clear sound;
    %                 end
    %             end
    %         end
    %         %plot(app.UIAxes,app.freqVect,app.timeLeft,'r-x',app.freqVect,app.timeRight,'b-o');
    %         app.startMeasFlag = 0;
    %         app.STARTButton.Text = 'start';
    %     end
    % 
    % 
    % 
    %     function results = Meas02(app)
    % 
    %     end
    % end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NASTAVENButton
        function NASTAVENButtonPushed(app, event)
            app.nastaveni=nastaveni(app,app.freqVect);
            %app.importdata(app,);

        end

        % Button pushed function: NVODButton
        function NVODButtonPushed(app, event)
            app.navod=navod(app);
        end

        % Value changed function: novaFrekvenceZobrazeni
        function novaFrekvenceZobrazeniValueChanged(app, event)
            value = app.novaFrekvenceZobrazeni.Value;

        end

        % Value changed function: frekvenceMaskTonu
        function frekvenceMaskTonuValueChanged(app, event)
            value = app.frekvenceMaskTonu.Value;

        end

        % Value changed function: intenzitaMaskTonu
        function intenzitaMaskTonuValueChanged(app, event)
            value = app.intenzitaMaskTonu.Value;

        end

        % Value changed function: pasmoMinFreq
        function pasmoMinFreqValueChanged(app, event)
            value = app.pasmoMinFreq.Value;

        end

        % Value changed function: sumIntenzita
        function sumIntenzitaValueChanged(app, event)
            value = app.sumIntenzita.Value;

        end

        % Value changed function: pasmoMaxFreq
        function pasmoMaxFreqValueChanged(app, event)
            value = app.pasmoMaxFreq.Value;

        end

        % Value changed function: mask
        function maskValueChanged(app, event)
            value = app.mask.Value;

        end

        % Value changed function: zvysovani
        function zvysovaniValueChanged(app, event)
            value = app.zvysovani.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            % delete(app.nastaveni)
            %delete(app.navod)

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            %AL = [8.89182357831544	5.93954344481664	10.2574876337944	3.45509667391978	7.44112118393599	5.06912356105390	13.8889059673946	41.7715719952750	36.2341162287780	48.0916778223027];
            %AR = [16.0637229280463	10.9009099238993	2.96930434778657	2.08140809850342	3.15865339760848	1.65388362300003	11.1032749671450	19.4895627025782	17.0245532186875	67.6337379184429];
            % plot(app.UIAxes,app.freqVect,AL,'r-x',app.freqVect,AR,'b-o');
            if app.startMeasFlag == 0
                % app.Measurement();
                app.STARTButton.Text = 'RESTART';
                app.STARTButton.BackgroundColor = 'r';
                app.SlysenoButton.BackgroundColor = [0.47,0.67,0.19];
                app.startMeasFlag = 1;
                app.dontSaveTime = 1;
                SlysenoButtonPushed(app);

            elseif app.startMeasFlag == 1
                %app.buttonPushed =1;
                app.STARTButton.Text = 'START';
                app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                app.SlysenoButton.BackgroundColor = 'r';
                app.startMeasFlag = 0;
                app.measCounter = 1;
                app.leftMeas = 1;
                clear sound;


            end


        end

        % Button pushed function: SlysenoButton
        function SlysenoButtonPushed(app, event)

            if app.zvysovani.Value == 0

                P_sampled = importdata('funkce60.mat');
                P_sampled = P_sampled'; % transpozice vektoru
                P_sampled(end) = [];    % zkrácení vektoru aby měl požadovanou délku
                Fs = 44100 ;   % nastavení vzorkovacího kmitočtu
                t_max = 60;   %časová délka signálu ale aby v gefu byla tahle hodnota je zapotřebí poděli pi*2(v grafu na x ukáže sice 10 ale)
                t = 0: (2*pi)/Fs : (2*pi*t_max - (2*pi)/Fs); %nastavení časové základny
                f = app.freqVect;%[125, 250, 500, 1000, 1500, 2000, 3000, 4000, 6000, 8000];

                clear sound;
                if app.dontSaveTime == 0
                    if app.leftMeas == 0
                        app.timeRight(app.measCounter) = toc;
                    elseif app.leftMeas == 1
                        if app.measCounter > 1
                            app.timeLeft(app.measCounter-1) = toc;
                        end
                    end
                else
                    app.dontSaveTime =0;
                end


                if app.startMeasFlag == 1
                    if app.measCounter < numel(f)+1
                        if app.leftMeas == 1
                            frequence = f(app.measCounter);
                            signal = sin(frequence*t);
                            signal = signal.*0.2;%P_sampled;
                            %sound(signal);
                            sound([signal;zeros(size(t))],Fs);
                            tic
                            app.leftMeas = 0;
                        elseif app.leftMeas == 0
                            frequence = f(app.measCounter);
                            signal = sin(frequence*t);
                            signal = signal.*0.2;%P_sampled;
                            %sound(signal);
                            sound([zeros(size(t)); signal],Fs);
                            tic
                            app.measCounter =  app.measCounter + 1;
                            app.leftMeas =1;
                        end
                    else
                        %restarting
                        app.startMeasFlag = 0;
                        app.STARTButton.Text = 'START';
                        app.STARTButton.BackgroundColor = [0.47,0.67,0.19];
                        app.SlysenoButton.BackgroundColor = 'r';


                        Norm_P_sampled = P_sampled./ max(P_sampled);
                        for k= 1:length(app.timeLeft)
                            timePointLeft(k) = floor((app.timeLeft(k)/t_max)*length(t));
                            ampiltudeLeft(k) = Norm_P_sampled(timePointLeft(k))*100;
                        end
                        for j = 1:length(app.timeRight)
                            timePointRight(j) = floor((app.timeRight(j)/t_max)*length(t));
                            ampiltudeRight(j) = Norm_P_sampled(timePointRight(j))*100;
                        end
                        plot(app.UIAxes,f,ampiltudeRight,'r-x',f,ampiltudeLeft,'b-o');
                        app.measCounter = 1;
                        clear sound;

                    end

                end
            elseif app.zvysovani.Value == 1
                AL = [8.89182357831544	5.93954344481664	10.2574876337944	3.45509667391978	7.44112118393599	5.06912356105390	13.8889059673946	41.7715719952750	36.2341162287780	48.0916778223027];
                AR = [16.0637229280463	10.9009099238993	2.96930434778657	2.08140809850342	3.15865339760848	1.65388362300003	11.1032749671450	19.4895627025782	17.0245532186875	67.6337379184429];
                plot(app.UIAxes,app.freqVect,AL,'r-x',app.freqVect,AR,'b-o');
            end
        end

        % Value changed function: vektortestovacchfrekvencEditField
        function vektortestovacchfrekvencEditFieldValueChanged(app, event)
            value = app.vektortestovacchfrekvencEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AUDIOGRAM')
            xlabel(app.UIAxes, 'Frekvence Hz')
            ylabel(app.UIAxes, 'SPL dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 8000];
            app.UIAxes.YLim = [0 90];
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.XAxisLocation = 'top';
            app.kontrolatestovachotnuLabel = uilabel(app.UIFigure);
            app.kontrolatestovachotnuLabel.Text = 'kontrola testovacího tónu:';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.kontrolamaskovachotnuLabel = uilabel(app.UIFigure);
            app.kontrolamaskovachotnuLabel.Text = 'kontrola maskovacího tónu:';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.kontrolamaskovachoumuLabel = uilabel(app.UIFigure);
            app.kontrolamaskovachoumuLabel.Text = 'kontrola maskovacího šumu:';
            app.HzLabel_3 = uilabel(app.UIFigure);
            app.HzLabel_3.Text = 'Hz';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = 'dB';
            app.NASTAVENButton = uibutton(app.UIFigure, 'push');
            app.NASTAVENButton.ButtonPushedFcn = createCallbackFcn(app, @NASTAVENButtonPushed, true);
            app.NASTAVENButton.Text = 'NASTAVENÍ';
            app.nastavenhodnotyLabel = uilabel(app.UIFigure);
            app.nastavenhodnotyLabel.Text = 'nastavené hodnoty';
            app.NVODButton = uibutton(app.UIFigure, 'push');
            app.NVODButton.ButtonPushedFcn = createCallbackFcn(app, @NVODButtonPushed, true);
            app.NVODButton.Text = 'NÁVOD';
            app.Novfrekvence120000HzEditFieldLabel = uilabel(app.UIFigure);
            app.Novfrekvence120000HzEditFieldLabel.Text = 'Nová frekvence  (1-20 000 Hz)';
            app.novaFrekvenceZobrazeni = uieditfield(app.UIFigure, 'numeric');
            app.novaFrekvenceZobrazeni.ValueChangedFcn = createCallbackFcn(app, @novaFrekvenceZobrazeniValueChanged, true);
            app.frekvencetnu120000HzEditFieldLabel = uilabel(app.UIFigure);
            app.frekvencetnu120000HzEditFieldLabel.Text = ' frekvence tónu (1-20 000 Hz)';
            app.frekvenceMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.frekvenceMaskTonu.ValueChangedFcn = createCallbackFcn(app, @frekvenceMaskTonuValueChanged, true);
            app.intenzita090dBEditFieldLabel = uilabel(app.UIFigure);
            app.intenzita090dBEditFieldLabel.Text = 'intenzita  (0-90 dB)';
            app.intenzitaMaskTonu = uieditfield(app.UIFigure, 'numeric');
            app.intenzitaMaskTonu.ValueChangedFcn = createCallbackFcn(app, @intenzitaMaskTonuValueChanged, true);
            app.frekvennpsmo120000HzodEditFieldLabel = uilabel(app.UIFigure);
            app.frekvennpsmo120000HzodEditFieldLabel.Text = 'frekvenční pásmo (1-20000 Hz) od:';
            app.pasmoMinFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMinFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMinFreqValueChanged, true);
            app.intenzita090dBEditField_2Label = uilabel(app.UIFigure);
            app.intenzita090dBEditField_2Label.Text = 'intenzita  (0-90 dB)';
            app.sumIntenzita = uieditfield(app.UIFigure, 'numeric');
            app.sumIntenzita.ValueChangedFcn = createCallbackFcn(app, @sumIntenzitaValueChanged, true);
            app.doEditFieldLabel = uilabel(app.UIFigure);
            app.doEditFieldLabel.Text = 'do:';
            app.pasmoMaxFreq = uieditfield(app.UIFigure, 'numeric');
            app.pasmoMaxFreq.ValueChangedFcn = createCallbackFcn(app, @pasmoMaxFreqValueChanged, true);
            app.zvysovaniintenzity1schod0spojEditFieldLabel = uilabel(app.UIFigure);
            app.zvysovaniintenzity1schod0spojEditFieldLabel.Text = 'zvysovani intenzity(1=schod, 0 = spoj)';
            app.zvysovani = uieditfield(app.UIFigure, 'numeric');
            app.zvysovani.ValueChangedFcn = createCallbackFcn(app, @zvysovaniValueChanged, true);
            app.masknetestucha1ANO0NEEditFieldLabel = uilabel(app.UIFigure);
            app.masknetestucha1ANO0NEEditFieldLabel.Text = 'mask netest ucha (1= ANO, 0 = NE)';
            app.mask = uieditfield(app.UIFigure, 'numeric');
            app.mask.ValueChangedFcn = createCallbackFcn(app, @maskValueChanged, true);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SlysenoButton = uibutton(app.UIFigure, 'push');
            app.SlysenoButton.ButtonPushedFcn = createCallbackFcn(app, @SlysenoButtonPushed, true);
            app.SlysenoButton.Text = 'SLYŠENO';
            app.vektortestovacchfrekvencEditFieldLabel = uilabel(app.UIFigure);
            app.vektortestovacchfrekvencEditFieldLabel.Text = 'vektor testovacích frekvencí';
            app.vektortestovacchfrekvencEditField = uieditfield(app.UIFigure, 'text');
            app.vektortestovacchfrekvencEditField.ValueChangedFcn = createCallbackFcn(app, @vektortestovacchfrekvencEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hlavniOkno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end